classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        Panel                       matlab.ui.container.Panel
        SELECTAUDIODropDown_2Label  matlab.ui.control.Label
        SELECTAUDIODropDown         matlab.ui.control.DropDown
        stopButton                  matlab.ui.control.Button
        resumeButton                matlab.ui.control.Button
        pauseButton                 matlab.ui.control.Button
        playButton                  matlab.ui.control.Button
        VOLUMELabel_2               matlab.ui.control.Label
        volumeKnob                  matlab.ui.control.Knob
        FREQUENCYLabel              matlab.ui.control.Label
        volumeKnob_2                matlab.ui.control.Knob
        UIAxes                      matlab.ui.control.UIAxes
        UIAxes_2                    matlab.ui.control.UIAxes
        ContextMenu                 matlab.ui.container.ContextMenu
        Menu                        matlab.ui.container.Menu
        Menu2                       matlab.ui.container.Menu
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: playButton
        function playButtonPushed(app, event)
            global audioFile;
            stop(audioFile);
            play(audioFile);
        end

        % Button pushed function: pauseButton
        function pauseButtonPushed(app, event)
            global audioFile;
            pause(audioFile);
        end

        % Button pushed function: resumeButton
        function resumeButtonPushed(app, event)
            global audioFile;
            resume(audioFile);
        end

        % Button pushed function: stopButton
        function stopButtonPushed(app, event)
            global audioFile;
            stop(audioFile);
        end

        % Value changed function: SELECTAUDIODropDown
        function SELECTAUDIODropDownValueChanged(app, event)
            global audioFile; %variable stores the audioFile that will be played
            file = app.SELECTAUDIODropDown.Value;
            [y, Fs] = audioread(file);
            audioFile = audioplayer(y,Fs);%set the global variable for audioFile to the selected audio file
            
            %plot the signal in time domain
            Mono = mean(y,2);
            t = linspace(0, length(y)/Fs,length(y));
            plot(app.UIAxes,t, Mono);%setting the plot to the audio signal info in time domain
            
            %plot the signal in frequency domain            
            nfft = 1024;
            f = linspace(0, Fs, nfft);
            Y = abs(fft(y,nfft));
            plot(app.UIAxes_2, f(1:nfft/2), Y(1:nfft/2));
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            clear global %will clear all of the global variable declarations
            clearvars -global;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.Panel = uipanel(app.UIFigure);
            app.SELECTAUDIODropDown_2Label = uilabel(app.Panel);
            app.SELECTAUDIODropDown_2Label.Text = 'SELECT AUDIO';
            app.SELECTAUDIODropDown = uidropdown(app.Panel);
            app.SELECTAUDIODropDown.Items = {'', 'All I Want for Christmas.mp3', 'Fight Song.mp3', 'Motivational Speech.mp3', 'Police Siren.mp3'};
            app.SELECTAUDIODropDown.ValueChangedFcn = createCallbackFcn(app, @SELECTAUDIODropDownValueChanged, true);
            app.SELECTAUDIODropDown.Value = '';
            app.stopButton = uibutton(app.Panel, 'push');
            app.stopButton.ButtonPushedFcn = createCallbackFcn(app, @stopButtonPushed, true);
            app.stopButton.Text = 'STOP';
            app.resumeButton = uibutton(app.Panel, 'push');
            app.resumeButton.ButtonPushedFcn = createCallbackFcn(app, @resumeButtonPushed, true);
            app.resumeButton.Text = 'RESUME';
            app.pauseButton = uibutton(app.Panel, 'push');
            app.pauseButton.ButtonPushedFcn = createCallbackFcn(app, @pauseButtonPushed, true);
            app.pauseButton.Text = 'PAUSE';
            app.playButton = uibutton(app.Panel, 'push');
            app.playButton.ButtonPushedFcn = createCallbackFcn(app, @playButtonPushed, true);
            app.playButton.Text = 'PLAY';
            app.VOLUMELabel_2 = uilabel(app.Panel);
            app.VOLUMELabel_2.Text = 'VOLUME';
            app.volumeKnob = uiknob(app.Panel, 'continuous');
            app.volumeKnob.Value = 100;
            app.FREQUENCYLabel = uilabel(app.Panel);
            app.FREQUENCYLabel.Text = 'FREQUENCY';
            app.volumeKnob_2 = uiknob(app.Panel, 'continuous');
            app.volumeKnob_2.Limits = [-100 100];
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Time Domain')
            xlabel(app.UIAxes, 'TIME (s)')
            ylabel(app.UIAxes, 'AMPLITUDE')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Frequency Domain')
            xlabel(app.UIAxes_2, 'FREQUENCY (Hz)')
            ylabel(app.UIAxes_2, 'POWER SPECTRUM (dB)')
            zlabel(app.UIAxes_2, 'Z')
            app.ContextMenu = uicontextmenu(app.UIFigure);
            % Assign app.ContextMenu
            app.playButton.ContextMenu = app.ContextMenu;
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end